classdef SegTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ML_Image                   matlab.ui.control.UIAxes
        LoadButton                 matlab.ui.control.Button
        SelectMLSegmentation       matlab.ui.control.Button
        SelectinFormSegmentation   matlab.ui.control.Button
        DrawNewSegmentationButton  matlab.ui.control.Button
        MachineLearningSegmentationLabel  matlab.ui.control.Label
        inFormSegmentationLabel    matlab.ui.control.Label
        GobacktolastcellButton     matlab.ui.control.Button
        ML_Image_2                 matlab.ui.control.UIAxes
        IF_Image                   matlab.ui.control.UIAxes
        IF_Image_2                 matlab.ui.control.UIAxes
        aSliderLabel               matlab.ui.control.Label
        aSlider                    matlab.ui.control.Slider
        bSliderLabel               matlab.ui.control.Label
        bSlider                    matlab.ui.control.Slider
        ButtonGroup                matlab.ui.container.ButtonGroup
        DAPIButton                 matlab.ui.control.RadioButton
        MembraneButton             matlab.ui.control.RadioButton
        ToggleSegButton            matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        im_ML % Machine learning label matrix
        im_IF % inForm label matrix
        nRows % rows
        nCols % columns
        mem_dapi_im % dapi and membrane component layers in column vector format
        mycols = [0 0 1; % DAPI
                 1 1 1];   % Membrane
       wd % directory with the inform and ML_data subfolders in it
       fname % current image name
       a_m = 2.5;
       a_d = 2.5;
       b_m = 200;
       b_d = 200;
       IF_cells % cell array of cells in inForm label matrix
       ML_cells % cell array of cells in ML label matrix
       m % temporary centroid or midpoint variable passed between functions, created from label matrix 
       IF_table % intial table with all inForm cells in it
       ML_table %intial table with all machine learning cells in it
       cells_n % cells variable passed between functions
       q % temporary table
       overlap_table % final\ resulting table with overlaps and other relevant info
       o_tbl_IF
       o_tbl_ML
       n % current cell row for analysis
       %
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            %
            ML_fd = 'ML_data';
            app.fname = extractBetween(imagefile,'Component_Tiffs\','_component');
            app.fname = app.fname{1};
            app.wd = extractBefore(imagefile, ['\',ML_fd,'\Component_Tiffs']);
            % Get the ML image
            try
                for i1 = 1:2
                    im_ML_tmp(:,:,i1) = imread(imagefile, i1);
                end
                app.im_ML = im_ML_tmp;
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding ML Image Error');
                return;
            end            
            %
            % get the inform image name and number of layers
            %
            imagefile2 = replace(imagefile, ML_fd, 'inform_data');
            imagefile2 = replace(imagefile2,'_seg','_w_seg');
            %
            try 
                im_info = imfinfo(imagefile2);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding inForm Image Error');
                return;
            end
            %
            layers = length(im_info);
            app.nRows = im_info(1).Height;
            app.nCols = im_info(1).Width;
            %
            if layers ~= 15
               uialert(app.UIFigure, 'inForm Image Error: MISSING IMAGE LAYERS');
               return;
            end
            %
            % read INFORM IMAGE
            %
            ic = 0;
            for i1 = 11:15
                ic = ic + 1;
                im_IF_tmp1(:,:,ic) = imread(imagefile2,i1);
            end
            app.im_IF = im_IF_tmp1;
            %
            for i1 = [1, 9]
                im = imread(imagefile2, i1);
                im = im ./ max(im,[],'all');
                im_IF_tmp(:,i1) = reshape(im, [], 1);
            end
            % 
            % Display DAPI and Membrane
            %
            app.mem_dapi_im = im_IF_tmp(:,[1 9]);
            %
            disp_images(app, 1)
            %
            comp_p_file_IF = [app.wd,'\inform_data\Component_Tiffs\',app.fname,'_overlap_table.xlsx'];
            %
            if exist(comp_p_file_IF,'file')
               app.overlap_table = readtables(comp_p_file_IF);
            else
                compute_pairs(app)
            end
            %
            app.n = 1;
            disp_images(app,1,app.n,height(app.overlap_table))
        end
        
        function compute_pairs(app)
            %%
            %
            % this is where the tables are computed for cells and cell overlap pct is
            % assigned
            %
            %%
            %
            % ML cell table
            %
            app.cells_n = label2idx(app.im_ML)';
            m1 = regionprops(app.im_ML,'centroid');
            app.m = struct2cell(m1(:))';
            crt_int_tbl(app, 'ML');
            %
            % get distinct objects in inform
            %
            getIFdistinct(app);
            %
            % create the IF table
            %
            crt_int_tbl(app, 'IF');
            %
            % get the cells which pixels overlap with ML
            %
            comp_overlap(app);
            app.q.jp = app.q.IF_pct .* app.q.ML_pct;
            %
            app.q.jp = app.q.IF_pct .* app.q.ML_pct;
            %
            add_no(app);
            x = app.q;
            x.include_cell = zeros(height(x),1);
            c = zeros(height(x),1);
            c = num2cell(c);
            x.final_cells = c;
            x.final_cell_x = c;
            x.final_cell_y = c;
            %
            app.overlap_table = x;
            %
            writetable(x, [app.wd,'\inform_data\Component_Tiffs\',app.fname,'_overlap_table.xlsx'])
            %
        end
        
        function crt_int_tbl(app, tt)
            %%
            % intialize the table for the overlap computation
            %
            %%
            cells_n1 = app.cells_n;
            m1 = app.m;
            %
            tbl = table();
            tbl.([tt,'_cellid']) = (1:length(cells_n1))';
            tbl.([tt,'_cells']) = cells_n1;
            tbl.([tt,'_centriods']) = m1;
            [x,y] = cellfun(@(x)ind2sub([app.nRows,app.nCols],x),cells_n1,'Uni',0);
            tbl.([tt,'_x']) = x;
            tbl.([tt,'_y']) = y;
            tbl.([tt,'_n_pixels']) = cellfun('length',cells_n1);
            %
            if strcmp(tt, 'ML')
                app.ML_table = tbl;
            elseif strcmp(tt,'IF')
                app.IF_table = tbl;
            end
            %
        end
        
        function getIFdistinct(app)
            %%
            % get the distinct cell objects from both inform cell segmentation layers
            %
            %%
            %
            % get distinct objects in inform
            %
            im = app.im_IF(:,:,1);
            IF_cells_1 = label2idx(im);
            m1 = regionprops(im,'centroid');
            m1 = struct2cell(m1(:))';
            %
            im = app.im_IF(:,:,2);
            IF_cells_2 = label2idx(im);
            m2 = regionprops(im,'centroid');
            m2 = struct2cell(m2(:))';
            %
            if length(IF_cells_1) > length(IF_cells_2)
                nn = length(IF_cells_1);
            else
                nn = length(IF_cells_2);
            end
            %
            IF_cellso = cell(nn, 1);
            mo = cell(nn,1);
            %
            ii = ~cellfun(@isempty,IF_cells_1);
            IF_cellso(ii) = IF_cells_1(ii);
            mo(ii) = m1(ii);
            %
            ii = ~cellfun(@isempty,IF_cells_2);
            IF_cellso(ii) = IF_cells_2(ii);
            mo(ii) = m2(ii);
            %
            app.cells_n = IF_cellso;
            app.m = mo;
            %
        end
        
        function comp_overlap(app)
            %%
            % This function computes the overlaps and adds them to the appropriate rows
            % in the table
            %
            %%
            o = [];
            for level = 1:2
                im1 = app.im_IF(:,:,level);
                overlap = im1 > 0 & app.im_ML > 0;
                pairs = [im1(overlap), app.im_ML(overlap)];
                [ii, ~, kk] = unique(pairs, 'rows');
                pairs_cnt = [ii,accumarray(kk,1)];
                %
                idx1 = pairs_cnt(:,1);
                idx2 = pairs_cnt(:,2);
                %
                crt_ovr_tbl(app,idx1,...
                    pairs_cnt(:,2) ,pairs_cnt(:,3), 'IF');
                %
                crt_ovr_tbl(app,idx2,...
                    pairs_cnt(:,1) ,pairs_cnt(:,3), 'ML');
                %
                tbl = outerjoin(app.o_tbl_IF,app.o_tbl_ML,'MergeKeys',1);
                %
                tbl.IF_level = repmat(level, height(tbl),1);
                o = [o;tbl];
            end
            %
            app.q = o;
            %
        end
        
        function crt_ovr_tbl(app,idx,pairs,cnt,tt)
            %%
            % define the table with the overlaps in it
            %
            %%
            if strcmp(tt, 'ML')
                tbl_in = app.ML_table;
            elseif strcmp(tt, 'IF')
                tbl_in = app.IF_table;
            end
            %
            tbl = tbl_in(idx,:);
            tbl.([tt,'_overlap']) =  cnt;
            tbl.([tt,'_pct']) = double(tbl.([tt,'_overlap']))...
                ./ tbl.([tt,'_n_pixels']);
            tbl.([tt,'_paired_w']) = pairs;
            tbl.cellid = (1:height(tbl))';
            %
            if strcmp(tt, 'ML')
                app.o_tbl_ML = tbl;
            elseif strcmp(tt, 'IF')
                app.o_tbl_IF = tbl;
            end
            %
        end
        
        function add_no(app)
            %
            tbl = app.q;
            colnames = tbl.Properties.VariableNames;
            %
            z = height(tbl);
            IF_tableo = app.IF_table;
            idx1 = unique(IF_tableo.IF_cellid);
            idx2 = unique(tbl.IF_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, IF_tableo, colnames, z);
            %
            z = height(app.q);
            ML_tableo = app.ML_table;
            idx1 = unique(ML_tableo.ML_cellid);
            idx2 = unique(tbl.ML_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, ML_tableo, colnames, z);
            %
        end
        
        function no_o_tbl(app, idx, tbl_in, colnames, z)
            %%
            % create the table of the size of the overlap table for cells without
            % overlaps
            %
            %%
            no_tbl = tbl_in;
            no_tbl(idx,:) = [];
            no_tbl.cellid = (z + 1:z + height(no_tbl))';
            colnames_no = no_tbl.Properties.VariableNames;
            ii = ~ismember(colnames, colnames_no);
            tbl = array2table(zeros(height(no_tbl),sum(ii)));
            tbl = [no_tbl, tbl];
            tbl.Properties.VariableNames = [colnames_no,colnames(ii)];
            %
            tt = {'IF','ML'};
            for i1 = 1:2
                tbl.([tt{i1},'_cells']) = num2cell(tbl.([tt{i1},'_cells']));
                tbl.([tt{i1},'_centriods']) = num2cell(tbl.([tt{i1},'_centriods']));
                tbl.([tt{i1},'_y']) = num2cell(tbl.([tt{i1},'_y']));
                tbl.([tt{i1},'_x']) = num2cell(tbl.([tt{i1},'_x']));
            end
            %
            app.q = [app.q; tbl];
            %
        end
        
        function disp_images(app, nm, n1, nall)
            if nm
                ff = app.fname;
                if nargin > 2
                    n1 = num2str(n1);
                    nall = num2str(nall);
                    app.UIFigure.Name = ['Segmentation Tool: ',ff, '; Cell: ', n1, 'of', nall];
                else
                    app.UIFigure.Name = ['Segmentation Tool: ',ff];
                end
            end
            %
            imshow2(app, app.ML_Image, 1, 1)
            imshow2(app, app.ML_Image_2, 2, 1)
            %
            imshow2(app, app.IF_Image, 1, 2)
            imshow2(app, app.IF_Image_2, 2, 2)
            %
        end
        
        function imshow2(app, loc, layer, s_layer)
            %
            C = app.nCols;
            R = app.nRows;
            %
            im = app.mem_dapi_im;
            mycols_n = app.mycols;
            %
            % if the cell counter is filled then cut that cell out of the
            % image
            %
            if ~isempty(app.n)
                inp = app.n;
                x = app.overlap_table;
                if x.IF_n_pixels(inp) > x.ML_n_pixels(inp)
                    center = x.IF_centriods(inp);
                else
                    center = x.ML_centriods(inp);
                end
                %
                x_center = center{1}(1);
                y_center = center{1}(2);
                ll = floor(x_center - 50);
                rr = floor(x_center + 50);
                tt = floor(y_center - 50);
                bb = floor(y_center + 50);
                %
                im = app.mem_dapi_im;
                im = (225 .* asinh(2 .* im)) * app.col;
                %
                % if segmentation is turned on apply a segmentation masks
                %
                ii = app.ToggleSegButton.Value;
                if ii
                    im2 = zeros(R * C, 3);
                    if s_layer == 2
                        ii = x.IF_cells{inp};
                    else
                        ii = x.ML_cells{inp};
                    end
                    im2(ii,1) = repmat(.4 * 255, length(ii), 1);
                    im = im + im2;
                end
                im = reshape(im, R, C, 3);
                %
                im = im(tt(1):bb(1),ll(1):rr(1),:);
                %
            end
            %
            % DAPI image
            %
            ii = app.DAPIButton.Value;
            if ii
                app.a_d = .05 * app.aSlider.Value;
                app.b_d = 4 * app.bSlider.Value;
            end
            im_d =  app.b_d .* asinh(app.a_d .* im(:,1)) * mycols_n(1,:);
            %
            % Membrane image
            %
            if layer ~= 1
                ii = app.MembraneButton.Value;
                if ii
                    app.a_m = .05 * app.aSlider.Value;
                    app.b_m = 4 * app.bSlider.Value;
                end
                im =  app.b_m .* asinh(app.a_m .* im(:,2)) * mycols_n(2,:);
                %
                im_d = im + im_d;
            end
            %
            im = reshape(im_d,R,C,3);
            im = uint8(im);
            %
            imshow(im, 'parent',loc);
            %
        end
        %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Configure image axes
            app.ML_Image.Visible = 'off';
            app.ML_Image.Colormap = gray(256);
            axis(app.ML_Image, 'image');
            %
            app.ML_Image_2.Visible = 'off';
            app.ML_Image_2.Colormap = gray(256);
            axis(app.ML_Image_2, 'image');
            %
            app.IF_Image.Visible = 'off';
            app.IF_Image.Colormap = gray(256);
            axis(app.IF_Image, 'image');
            %
            app.IF_Image_2.Visible = 'off';
            app.IF_Image_2.Colormap = gray(256);
            axis(app.IF_Image_2, 'image');
            %
            app.aSlider.Value = 50;
            app.bSlider.Value = 50;
            % Update the image and histograms
            % updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update thmage and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               imagefile = [p f];
               updateimage(app, imagefile);
            end
        end

        % Button pushed function: SelectinFormSegmentation
        function SelectinFormSegmentationButtonPushed(app, event)
            
        end

        % Button pushed function: SelectMLSegmentation
        function SelectMLSegmentationButtonPushed(app, event)
            
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            disp_images(app, 0)
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            disp_images(app, 0)
        end

        % Size changed function: ButtonGroup
        function ButtonGroupSizeChanged(app, event)
            
        end

        % Value changed function: ToggleSegButton
        function ToggleSegButtonValueChanged(app, event)
            disp_images(app, 0)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentation Tool';
            app.ML_Image = uiaxes(app.UIFigure);
            app.ML_Image.XTick = [];
            app.ML_Image.XTickLabel = {'[ ]'};
            app.ML_Image.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load new image ';
            app.SelectMLSegmentation = uibutton(app.UIFigure, 'push');
            app.SelectMLSegmentation.ButtonPushedFcn = createCallbackFcn(app, @SelectMLSegmentationButtonPushed, true);
            app.SelectMLSegmentation.Text = 'Select ML Segmentation';
            app.SelectinFormSegmentation = uibutton(app.UIFigure, 'push');
            app.SelectinFormSegmentation.ButtonPushedFcn = createCallbackFcn(app, @SelectinFormSegmentationButtonPushed, true);
            app.SelectinFormSegmentation.Text = 'Select inForm Segmentation';
            app.DrawNewSegmentationButton = uibutton(app.UIFigure, 'push');
            app.DrawNewSegmentationButton.Text = 'Draw New Segmentation';
            app.MachineLearningSegmentationLabel = uilabel(app.UIFigure);
            app.MachineLearningSegmentationLabel.Text = 'Machine Learning Segmentation';
            app.inFormSegmentationLabel = uilabel(app.UIFigure);
            app.inFormSegmentationLabel.Text = 'inForm Segmentation';
            app.GobacktolastcellButton = uibutton(app.UIFigure, 'push');
            app.GobacktolastcellButton.Text = 'Go back to last cell';
            app.ML_Image_2 = uiaxes(app.UIFigure);
            app.ML_Image_2.XTick = [];
            app.ML_Image_2.XTickLabel = {'[ ]'};
            app.ML_Image_2.YTick = [];
            app.IF_Image = uiaxes(app.UIFigure);
            app.IF_Image.XTick = [];
            app.IF_Image.XTickLabel = {'[ ]'};
            app.IF_Image.YTick = [];
            app.IF_Image_2 = uiaxes(app.UIFigure);
            app.IF_Image_2.XTick = [];
            app.IF_Image_2.XTickLabel = {'[ ]'};
            app.IF_Image_2.YTick = [];
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ButtonGroupSizeChanged, true);
            app.DAPIButton = uiradiobutton(app.ButtonGroup);
            app.DAPIButton.Text = 'DAPI';
            app.DAPIButton.Value = true;
            app.MembraneButton = uiradiobutton(app.ButtonGroup);
            app.MembraneButton.Text = 'Membrane';
            app.ToggleSegButton = uibutton(app.UIFigure, 'state');
            app.ToggleSegButton.ValueChangedFcn = createCallbackFcn(app, @ToggleSegButtonValueChanged, true);
            app.ToggleSegButton.Text = 'ToggleSeg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end